classdef Economic_dispach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Econimic_dispatch             matlab.ui.Figure
        iteration                     matlab.ui.control.NumericEditField
        IterationLimitEditField       matlab.ui.control.NumericEditField
        IterationLimitEditFieldLabel  matlab.ui.control.Label
        ErrorEditField                matlab.ui.control.EditField
        TotalCosthEditField           matlab.ui.control.NumericEditField
        TotalCosthEditFieldLabel      matlab.ui.control.Label
        LamdaMWhEditField             matlab.ui.control.NumericEditField
        LamdaMWhEditFieldLabel        matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        RemoveButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        UITable                       matlab.ui.control.Table
        TotalLoadDemandMWEditField    matlab.ui.control.NumericEditField
        TotalLoadDemandMWEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


     properties (Access = private)
        selectedRow
        data
        load
        cost
        P
        iterationLimit
       
    end
    
    methods (Access = private)
        
        function UpdateRowName(app)
            % Updating row names according to the number of vectors
            vectorNames = cell(1, height(app.UITable.Data));
            for ii = 1 : height (app.UITable.Data)
                vectorNames{1, ii}= sprintf('Unit %d', ii);
            end
            app.UITable.RowName = vectorNames;
            app.UITable2.RowName= vectorNames;
           
        end
  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UITable.Data = [561 7.92 0.0562 150   600;
                                 310 7.85 0.00194 100   400;
                                 78 7.97 0.00482 50 200;] ;
             app.TotalLoadDemandMWEditField.Value=850;
             app.IterationLimitEditField.Value=1000;
             UpdateRowName(app)
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
              if isequal(height(app.selectedRow), 1) 
                    % If the height of event.Indices is one, it means a single row
                    % is selected and we can remove it, if you want to
                    % remove more rows, you can implement it easily
                    
                    if app.selectedRow > height(app.UITable.Data) % When the last row is clicked and removed, subsequent 
                        app.selectedRow = height(app.UITable.Data);  % removing will throw an error, remove last row instead
                    end
                    app.UITable.Data(app.selectedRow, :) = [];
                    UpdateRowName(app)
              end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; zeros([1,width(app.UITable.Data)])]; % Concantencate array 
            UpdateRowName(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.data=get(app.UITable,'Data');
            app.load=app.TotalLoadDemandMWEditField.Value;
            app.iterationLimit=app.IterationLimitEditField;
            app.ErrorEditField.Value="";
            
            b=app.data(:,2);
            c=app.data(:,3);
            Pl=app.data(:,4);
            Ph=app.data(:,5);
            dP=900;
            lamda=max(b); % assume lambda
            app.iteration.Value=0;
            while abs(dP)>0.0000001 && app.iteration.Value<app.IterationLimitEditField.Value
                app.P=(lamda-b)./c/2;
                app.P=min(app.P,Ph);
                app.P=max(app.P,Pl);
                dP=app.load-sum(app.P);
                lamda=lamda+dP*2/(sum(1./c));
               
                app.iteration.Value=app.iteration.Value+1;
            end
            if app.iteration.Value>= app.IterationLimitEditField.Value
                app.ErrorEditField.Value="Invalid input or Out of Iteration";
                app.LamdaMWhEditField.Value =0;
                app.TotalCosthEditField.Value=0;
                app.UITable2.Data=zeros([length(app.UITable2.Data) ,width(app.UITable2.Data)]);
            
            else 
                UpdateRowName(app)
                app.LamdaMWhEditField.Value=lamda;
                app.TotalCosthEditField.Value=sum(app.data(:,2)+b.*app.P+c.*app.P.*app.P); % Costs
                
                app.cost=app.data(:,1)+b.*app.P+c.*app.P.*app.P;
                M=[ app.P,app.cost];
                app.UITable2.Data=M;
                
                p=0:max(app.P);
                IC= b+2*c.*p;
    
                plot(app.UIAxes,p,lamda*ones(size(p)),'--',p,IC)
                legend(app.UIAxes,['Lamda';app.UITable.RowName])
            end

         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Econimic_dispatch = uifigure('Visible', 'off');
            app.Econimic_dispatch.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Econimic_dispatch);
            title(app.UIAxes, 'Incremental Cost Curves')
            xlabel(app.UIAxes, 'Power (MW)')
            ylabel(app.UIAxes, ' IC ($/MWh)')
            app.TotalLoadDemandMWEditFieldLabel = uilabel(app.Econimic_dispatch);
            app.TotalLoadDemandMWEditFieldLabel.Text = 'Total Load Demand (MW)';
            app.TotalLoadDemandMWEditField = uieditfield(app.Econimic_dispatch, 'numeric');
            app.TotalLoadDemandMWEditField.Limits = [0 Inf];
            app.TotalLoadDemandMWEditField.Value = 20;
            app.UITable = uitable(app.Econimic_dispatch);
            app.UITable.ColumnName = {'a'; 'b'; 'c'; 'Min (MW)'; 'Max (MW)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TextArea = uitextarea(app.Econimic_dispatch);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Fuel cost Function:'; 'C= a + b* P + c* P^2'; ''};
            app.LoadButton = uibutton(app.Econimic_dispatch, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddButton = uibutton(app.Econimic_dispatch, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.Econimic_dispatch, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.UITable2 = uitable(app.Econimic_dispatch);
            app.UITable2.ColumnName = {'P'; 'Cost'};
            app.UITable2.RowName = {};
            app.LamdaMWhEditFieldLabel = uilabel(app.Econimic_dispatch);
            app.LamdaMWhEditFieldLabel.Text = 'Lamda ($/MWh)';
            app.LamdaMWhEditField = uieditfield(app.Econimic_dispatch, 'numeric');
            app.LamdaMWhEditField.Editable = 'off';
            app.TotalCosthEditFieldLabel = uilabel(app.Econimic_dispatch);
            app.TotalCosthEditFieldLabel.Text = 'Total Cost ($/h)';
            app.TotalCosthEditField = uieditfield(app.Econimic_dispatch, 'numeric');
            app.TotalCosthEditField.Editable = 'off';
            app.ErrorEditField = uieditfield(app.Econimic_dispatch, 'text');
            app.ErrorEditField.Editable = 'off';
            app.IterationLimitEditFieldLabel = uilabel(app.Econimic_dispatch);
            app.IterationLimitEditFieldLabel.Text = 'Iteration Limit';
            app.IterationLimitEditField = uieditfield(app.Econimic_dispatch, 'numeric');
            app.iteration = uieditfield(app.Econimic_dispatch, 'numeric');
            app.iteration.Editable = 'off';
            % Show the figure after all components are created
            app.Econimic_dispatch.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Economic_dispach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Econimic_dispatch)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Econimic_dispatch)
        end
    end
end